classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainviewTab                     matlab.ui.container.Tab
        FileselectionPanel              matlab.ui.container.Panel
        LoadfileButton                  matlab.ui.control.Button
        SavevideoButton                 matlab.ui.control.Button
        batchsizeEditFieldLabel         matlab.ui.control.Label
        batchsizeEditField              matlab.ui.control.NumericEditField
        batchstrideEditFieldLabel       matlab.ui.control.Label
        batchstrideEditField            matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        wavelengthEditFieldLabel        matlab.ui.control.Label
        wavelengthEditField             matlab.ui.control.NumericEditField
        FsEditFieldLabel                matlab.ui.control.Label
        FsEditField                     matlab.ui.control.NumericEditField
        f1Label                         matlab.ui.control.Label
        f1EditField                     matlab.ui.control.NumericEditField
        f2EditFieldLabel                matlab.ui.control.Label
        f2EditField                     matlab.ui.control.NumericEditField
        pixwidthEditFieldLabel          matlab.ui.control.Label
        pixwidthEditField               matlab.ui.control.NumericEditField
        pixheightEditFieldLabel         matlab.ui.control.Label
        pixheightEditField              matlab.ui.control.NumericEditField
        registrationCheckBox            matlab.ui.control.CheckBox
        AutofocusButton                 matlab.ui.control.Button
        temporalfilterCheckBox          matlab.ui.control.CheckBox
        ParallelismDropDownLabel        matlab.ui.control.Label
        ParallelismDropDown             matlab.ui.control.DropDown
        positioninfileSliderLabel       matlab.ui.control.Label
        positioninfileSlider            matlab.ui.control.Slider
        temporalfilterEditField         matlab.ui.control.NumericEditField
        FiletypeDropDownLabel           matlab.ui.control.Label
        FiletypeDropDown                matlab.ui.control.DropDown
        PANICBUTTONButton               matlab.ui.control.Button
        ImageviewPanel                  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        UIAxes_2                        matlab.ui.control.UIAxes
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes_1                        matlab.ui.control.UIAxes
        Slider2                         matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        PatientinformationsPanel        matlab.ui.container.Panel
        FirstnameEditFieldLabel         matlab.ui.control.Label
        FirstnameEditField              matlab.ui.control.EditField
        LastnameEditFieldLabel          matlab.ui.control.Label
        LastnameEditField               matlab.ui.control.EditField
        YearofbirthEditFieldLabel       matlab.ui.control.Label
        YearofbirthEditField            matlab.ui.control.NumericEditField
        NotesTextAreaLabel              matlab.ui.control.Label
        NotesTextArea                   matlab.ui.control.TextArea
        FeaturesPanel                   matlab.ui.container.Panel
        CheckBox                        matlab.ui.control.CheckBox
        valEditFieldLabel               matlab.ui.control.Label
        valEditField                    matlab.ui.control.NumericEditField
        registrationCheckBox_2          matlab.ui.control.CheckBox
        aberrationcompensationCheckBox  matlab.ui.control.CheckBox
        temporalfilterCheckBox_2        matlab.ui.control.CheckBox
        additionalzernikeranksLabel     matlab.ui.control.Label
        additionalzernikeranksEditField  matlab.ui.control.NumericEditField
        optimizationplanDropDownLabel   matlab.ui.control.Label
        optimizationplanDropDown        matlab.ui.control.DropDown
        batchsizefactorLabel            matlab.ui.control.Label
        batchsizefactorEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        % skip callbacks if no file is loaded
        file_loaded
        
        filename % current file loaded
        filepath
        interferogram_stream
        
        % data related to current displayed frame
        frame_batch
        
        % misc proxy data
        Nx
        Ny
        gw
        kernel
        FH
        hologram
        DX % x circshift for registration
        DY % y circshift for registration
    end
    
    methods (Access = public)
        function compute_kernel(app, use_gpu)
            app.kernel = propagation_kernel(app.Nx, app.Ny, app.zEditField.Value, app.wavelengthEditField.Value, app.pixwidthEditField.Value, app.pixheightEditField.Value, use_gpu);
        end
    
        function compute_FH(app, use_gpu)
            if use_gpu
                app.FH = gpuArray(fftshift(fft2(app.frame_batch)) .* app.kernel);
            else
                app.FH = fftshift(fft2(app.frame_batch)) .* app.kernel;
            end
        end
    
        function compute_hologram(app, use_gpu)
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            app.hologram = reconstruct_hologram(app.FH, app.f1EditField.Value, app.f2EditField.Value, acquisition, app.gw, use_gpu);
        end
    
        function show_hologram(app)
            imshow(mat2gray(app.hologram), 'Parent', app.UIAxes_1);
        end
        
        function reg = compute_temporal_registration(app, progress_bar)
            % first pass
            % return a 2 x num_frames shift matrix
            % [dx1 ... dxn]
            % [dy1 ... dyn]
            
            % proxy variables to avoid
            % broadcasting the entire gui to worker
            % threads during parfor loop
            istream = app.interferogram_stream;
            j_win = app.batchsizeEditField.Value;
            j_step = app.batchstrideEditField.Value;
            local_f1 = app.f1EditField_2.Value;
            local_f2 = app.f2EditField_2.Value;
            local_gw = app.gw;
            local_kernel = app.kernel;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            
            % reset progress bar
            send(progress_bar, 1);
            num_batches = floor((istream.num_frames - j_win) / j_step);
            
            % construct holograms
            holograms = zeros(app.Nx,app.Ny,1,num_batches);
            parfor batch_idx = 1:num_batches-1
                local_tmp = istream.read_frame_batch(j_win, batch_idx * j_step);
                local_tmp = fftshift(fft2(local_tmp)) .* local_kernel;
                local_tmp = reconstruct_hologram(local_tmp, local_f1, local_f2, acquisition, local_gw, false);
               
                holograms(:,:,:,batch_idx) = mat2gray(gather(local_tmp));
                send(progress_bar, 0); % increment progress bar
            end
            
            % compute registrations
            [~, reg] = register_video(holograms); 
        end
    
        function defocus = compute_defocus(FH, f1, f2, gw, acquisition)
                optimizer = EntropyOptimization(f1, f2, 2, 0, -2, 2, 0, gw);

                defocus = optimizer.optimize(FH, local_f1, local_f2, acquisition, local_gw, 1, false);
        end
    end
    
    methods (Access = private)
        function output_fullfile = create_output_filename(input_filename, input_filepath, file_type)
            % construct output filename 
            % name.cine -> name.avi
            % add a number if file already exists
            path = input_filepath;
            [~,file,~] = fileparts(input_filename);
            suffix = 0;

            while exist(fullfile(path, sprintf("%s_%d.%s", file, suffix, file_type)))
                suffix = suffix + 1;
            end
            
            output_fullfile = sprintf("%s_%d.avi", file, suffix);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set app constants
            app.gw = 35;
            app.file_loaded = false;
            app.DX = 0;
            app.DY = 0;
        end

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            % open file
            % TODO: handle raw files if selected in drop down menu
            [app.filename, app.filepath] = uigetfile('.cine');
            app.interferogram_stream = CineReader(fullfile(app.filepath, app.filename));
            
            app.positioninfileSlider.Limits = [0, double(app.interferogram_stream.num_frames-1024)]; % TODO replace hardcoded 1024
            app.positioninfileSlider.Value = 0;
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            
            % display acquisition information
            app.FsEditField.Value = double(app.interferogram_stream.frame_rate);
            app.f2EditField.Value = floor(app.FsEditField.Value / (2*1000));
            app.pixwidthEditField.Value = 1 / double(app.interferogram_stream.horizontal_pix_per_meter);
            app.pixheightEditField.Value = 1 / double(app.interferogram_stream.vertical_pix_per_meter);
            app.Nx = double(app.interferogram_stream.frame_width);
            app.Ny = double(app.interferogram_stream.frame_height);
            
            % compute FH
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.file_loaded = true;
            
            % display interferogram on 2nd plot
            app.show_hologram();
            imshow(mat2gray(app.frame_batch(:,:,1)), 'Parent', app.UIAxes_2);
        end

        % Value changed function: zEditField
        function zEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f1EditField
        function f1EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: f2EditField
        function f2EditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: wavelengthEditField
        function wavelengthEditFieldValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: batchsizeEditField
        function batchsizeEditFieldValueChanged(app, event)
            if app.CheckBox.Value 
                % if the batch stride factor checkbox is checked
                % then we constrain stride == size
                % so here we need to update the stride as well
                app.batchstrideEditField.Value = app.batchsizeEditField.Value;
            end
            
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, 0);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: SavevideoButton
        function SavevideoButtonPushed(app, event)
            % main program loop
            % contructs video from gui parameters
            
            if ~app.file_loaded
                return
            end
            file = gui.create_output_filename(app.filepath, app.filename, app.FiletypeDropDown.Value);

            tic;
            num_batches = floor((app.interferogram_stream.num_frames - app.batchsizeEditField.Value) / app.batchstrideEditField.Value);
            video = zeros(app.Nx,app.Ny,1,num_batches);
            
            % create proxy variables so that the entire gui
            % is not broadcasted to workers
            % in the parfor loops
            istream = app.interferogram_stream;
            j_win = app.batchsizeEditField.Value;
            j_step = app.batchstrideEditField.Value;
            local_f1 = app.f1EditField.Value;
            local_f2 = app.f2EditField.Value;
            local_gw = app.gw;
            local_Nx = app.Nx;
            local_Ny = app.Ny;
            
            local_kernel = app.kernel;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            
            % parfor waitbar
            % we have to use a DataQueue
            % since there are no synchronization
            % primitives in matlab (mutex)
            D = parallel.pool.DataQueue;
            h = waitbar(0, 'Saving video...');
            afterEach(D, @update_waitbar);
            N = double(num_batches-1);
            p = 1;
                
            %% first pass - compute shifts for video registration
            registration_pass = app.registrationCheckBox_2.Value;
            
            if registration_pass
                shifts = app.compute_temporal_registration(D);               
                zernikes = evaluate_zernikes([1 1], [1 -1], app.Nx, app.Ny);
            else
                % unused variables to make matlab parfor
                % loop parser happy - stupid matlab
                shifts = zeros(2, istream.num_frames);
                zernikes = zeros(app.Nx, app.Ny, 2);
            end
            
            aberration_correction_pass = app.aberrationcompensationCheckBox.Value;
            
            defocus_zernike = evaluate_zernikes(2, 0, app.Nx, app.Ny);
            astig_zernikes = evaluate_zernikes([2 2], [-2 2], app.Nx, app.Ny);
            
            if aberration_correction_pass
                
            end
            
            %% video construction
            switch app.ParallelismDropDown.Value
                case 'multithread'
                    send(D,2);
                    parfor batch_idx = 1:num_batches-1
                        local_tmp = istream.read_frame_batch(j_win, batch_idx * j_step);
                        local_tmp = fftshift(fft2(local_tmp)) .* local_kernel;
                        
                        % add tilted phase for FH registration
                        if registration_pass
                            magic_number = 710;
                            zernike_coefs = shifts(:, batch_idx);
                            zernike_coefs(1) = zernike_coefs(1) * magic_number * 2 / local_Nx;
                            zernike_coefs(2) = zernike_coefs(2) * magic_number * 2 / local_Ny;
                            tilts = zernike_coefs(1) .* zernikes(:,:,1) + zernike_coefs(2) .* zernikes(:,:,2);
                            phases = exp(-1i * tilts);
                            local_tmp = local_tmp .* phases;
                        end
                        
                        % compute defocus
                        if aberration_correction_pass
                            optimizer = EntropyOptimization(local_f1, local_f2, 2, 0, -2.5, 2.5, 0, local_gw);

                            defocus = optimizer.optimize(local_tmp, local_f1, local_f2, acquisition, local_gw, 1, false);
                            fprintf('defocus = %f\n', defocus);
                            
                            local_tmp = local_tmp .* exp(-1i * defocus * defocus_zernike);
                            local_tmp = reconstruct_hologram(local_tmp, local_f1, local_f2, acquisition, local_gw, false);
                           
                            % optimize astigmatism
%                             optimizer = EntropyOptimization(local_f1, local_f2, [2 2], [-2 2], [-4 -4], [4 4], [0 0], local_gw);
%                             astigs = optimizer.optimize(local_tmp, local_f1, local_f2, acquisition, local_gw, 1, false);
%                             phase = astigs(1) * astig_zernikes(:,:,1) + astigs(2) * astig_zernikes(:,:,2);
%                             fprintf('astig1 = %f\nastig2 = %f', astigs(1), astigs(2));
%                             local_tmp = reconstruct_hologram(local_tmp, local_f1, local_f2, acquisition, local_gw, false, exp(-1i * phase));
                        else
                            local_tmp = reconstruct_hologram(local_tmp, local_f1, local_f2, acquisition, local_gw, false);
                        end

                        video(:,:,:,batch_idx) = mat2gray(gather(local_tmp));
                        send(D, 0);
                    end
                case 'gpu'
                    for batch_idx = 1:num_batches-1
                        app.frame_batch = istream.read_frame_batch(j_win, batch_idx * j_step);
                        app.compute_kernel(false);
                        app.compute_FH(true);
                        app.compute_hologram(true);
                        video(:,:,:,batch_idx) = app.hologram;
                        send(D, 0);
                    end
                case 'singlethread'
                    for batch_idx = 1:num_batches-1
                        app.frame_batch = istream.read_frame_batch(j_win, batch_idx * j_step);
                        app.compute_kernel(false);
                        app.compute_FH(false);
                        app.compute_hologram(false);
                        video(:,:,:,batch_idx) = app.hologram;
                        send(D, 0);
                    end
            end
            
            
            function update_waitbar(sig)
                % signal table
                % 0 => increment value
                % 1 => reset for stage 1 (registration)
                % 2 => reset for stage 2 (video computation)
                switch sig
                    case 0
                        waitbar(p/N, h);
                        p = p + 1;
                    case 1
                        waitbar(0,h,'Stage 1: video registration...');
                        p = 1;
                    case 2
                        waitbar(0,h,'Stage 2: video construction...');
                        p = 1;
                end
                
            end
        
            if app.registrationCheckBox.Value
                video = register_video(mat2gray(video));
            end
            
            % guassian temporal filter
            if app.temporalfilterCheckBox.Value
                windowSize = app.temporalfilterEditField.Value; 
                b = (1/windowSize)*ones(1,windowSize);
                a = 1;
                video = filter(b,a,video,[],4);
            end
            
            w = VideoWriter(sprintf('%s\\%s', path, file));
            open(w);
            writeVideo(w, mat2gray(video));
            close(w);
            close(h);
            toc;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            x_shift = floor(app.Nx * value);
            app.DX = x_shift;
            
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            value = app.Slider2.Value;
            y_shift = floor(app.Ny * value);
            app.DY = y_shift;
            if ~app.file_loaded
                return
            end
            
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Button pushed function: AutofocusButton
        function AutofocusButtonPushed(app, event)
            if ~app.file_loaded
                return
            end
            
            z0 = app.zEditField.Value;
            acquisition = DopplerAcquisition(app.Nx,app.Ny,app.FsEditField.Value/1000,app.zEditField.Value,app.wavelengthEditField.Value,app.DX,app.DY,app.pixwidthEditField.Value,app.pixheightEditField.Value);
            mask = construct_mask(0, app.gw, app.Nx, app.Ny);
            
            % free GPU memory
            app.FH = 0;
            
            z = autofocus(app.frame_batch, app.f1EditField.Value, app.f2EditField.Value, acquisition, z0, mask, app.gw, true);
            app.zEditField.Value = z;
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: positioninfileSlider
        function positioninfileSliderValueChanged(app, event)
            if ~app.file_loaded
                return
            end
            
            app.frame_batch = app.interferogram_stream.read_frame_batch(app.batchsizeEditField.Value, floor(app.positioninfileSlider.Value));
            app.compute_kernel(false);
            app.compute_FH(true);
            app.compute_hologram(true);
            app.show_hologram();
        end

        % Value changed function: aberrationcompensationCheckBox
        function aberrationcompensationCheckBoxValueChanged(app, event)
            value = app.aberrationcompensationCheckBox.Value;
            
            if value
                % turn on batch size factor widgets and set batch stride to
                % batch size
                app.CheckBox.Enable = true;
                app.batchsizefactorEditField.Enable = true;
                app.optimizationplanDropDown.Enable = true;
                app.additionalzernikeranksEditField.Enable = true;
            else
                app.CheckBox.Value = false;
                app.CheckBox.Enable = false;
                app.batchsizefactorEditField.Enable = false;
                app.optimizationplanDropDown.Enable = false;
                app.additionalzernikeranksEditField.Enable = false;
                app.batchstrideEditField.Editable = true;
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
            if value
                app.batchstrideEditField.Editable = false;
                app.batchstrideEditField.Value = app.batchsizeEditField.Value;
                color = [0.5, 0.5, 0.5];
            else
                app.batchstrideEditField.Editable = true;
                color = [0.8, 0.8, 0.8];
            end
            
            app.batchstrideEditField.FontColor = color;
        end

        % Value changed function: FiletypeDropDown
        function FiletypeDropDownValueChanged(app, event)
            value = app.FiletypeDropDown.Value;
            
            % unload current file
            app.file_loaded = false;
            
            % clear first image
            axes_handles_to_child_object = findobj(app.UIAxes_1, 'Type', 'image');
            if ~isempty(axes_handles_to_child_object)
                delete(axes_handles_to_child_object);
            end
            
            % clear second image
            axes_handles_to_child_object = findobj(app.UIAxes_2, 'Type', 'image');
            if ~isempty(axes_handles_to_child_object)
                delete(axes_handles_to_child_object);
            end
            
            switch value
                case 'raw'
                    % enables some parameter fields interactivity for parameters
                    % read in cine files that are not present in raw files
                    color = [0.8, 0.8, 0.8];
                    app.FsEditField.Editable = true;
                    app.pixheightEditField.Editable = true;
                    app.pixwidthEditField.Editable = true;
                case 'cine'
                    color = [0.5, 0.5, 0.5];
                    app.FsEditField.Editable = false;
                    app.pixheightEditField.Editable = false;
                    app.pixwidthEditField.Editable = false;
            end
            
            app.FsEditField.FontColor = color;
            app.pixheightEditField.FontColor = color;
            app.pixwidthEditField.FontColor = color;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.FileselectionPanel = uipanel(app.MainviewTab);
            app.FileselectionPanel.Tooltip = {'Laser wavelength in nanometers'};
            app.FileselectionPanel.Title = 'File selection';
            app.LoadfileButton = uibutton(app.FileselectionPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.SavevideoButton = uibutton(app.FileselectionPanel, 'push');
            app.SavevideoButton.ButtonPushedFcn = createCallbackFcn(app, @SavevideoButtonPushed, true);
            app.SavevideoButton.Text = 'Save video';
            app.batchsizeEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchsizeEditFieldLabel.Text = 'batch size';
            app.batchsizeEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchsizeEditField.Limits = [1 Inf];
            app.batchsizeEditField.ValueChangedFcn = createCallbackFcn(app, @batchsizeEditFieldValueChanged, true);
            app.batchsizeEditField.Tooltip = {'Number of interferograms used to generate a single image. Increase value for better video quality.'};
            app.batchsizeEditField.Value = 512;
            app.batchstrideEditFieldLabel = uilabel(app.FileselectionPanel);
            app.batchstrideEditFieldLabel.Text = 'batch stride';
            app.batchstrideEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.batchstrideEditField.Limits = [1 Inf];
            app.batchstrideEditField.Tooltip = {'Number of interferograms to skip between two images. Increase for a shorter video.'};
            app.batchstrideEditField.Value = 256;
            app.zEditFieldLabel = uilabel(app.FileselectionPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.zEditField.Limits = [0 Inf];
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @zEditFieldValueChanged, true);
            app.zEditField.Tooltip = {'Reconstruction distance in mm. Can be computed automatically with the ''Autofocus'' button.'};
            app.zEditField.Value = 0.22;
            app.wavelengthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.wavelengthEditFieldLabel.Text = 'wavelength';
            app.wavelengthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.wavelengthEditField.Limits = [0 Inf];
            app.wavelengthEditField.ValueChangedFcn = createCallbackFcn(app, @wavelengthEditFieldValueChanged, true);
            app.wavelengthEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.wavelengthEditField.Value = 7.85e-07;
            app.FsEditFieldLabel = uilabel(app.FileselectionPanel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.FsEditField.Limits = [0 Inf];
            app.FsEditField.Editable = 'off';
            app.FsEditField.Tooltip = {'Video sampling frequency in Hz'};
            app.f1Label = uilabel(app.FileselectionPanel);
            app.f1Label.Text = 'f1';
            app.f1EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.f1EditField.Limits = [0 Inf];
            app.f1EditField.ValueChangedFcn = createCallbackFcn(app, @f1EditFieldValueChanged, true);
            app.f1EditField.Tooltip = {'Frequency lower integration bound in kHz.'};
            app.f1EditField.Value = 8;
            app.f2EditFieldLabel = uilabel(app.FileselectionPanel);
            app.f2EditFieldLabel.Text = 'f2';
            app.f2EditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.f2EditField.Limits = [0 Inf];
            app.f2EditField.ValueChangedFcn = createCallbackFcn(app, @f2EditFieldValueChanged, true);
            app.f2EditField.Tooltip = {'Frequency upper integration bound in kHz.'};
            app.f2EditField.Value = 30;
            app.pixwidthEditFieldLabel = uilabel(app.FileselectionPanel);
            app.pixwidthEditFieldLabel.Text = 'pix width';
            app.pixwidthEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.pixwidthEditField.Limits = [0 Inf];
            app.pixwidthEditField.Editable = 'off';
            app.pixwidthEditField.Tooltip = {'Camera pixel width'};
            app.pixheightEditFieldLabel = uilabel(app.FileselectionPanel);
            app.pixheightEditFieldLabel.Text = 'pix height';
            app.pixheightEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.pixheightEditField.Limits = [0 Inf];
            app.pixheightEditField.Editable = 'off';
            app.pixheightEditField.Tooltip = {'Camera pixel height'};
            app.registrationCheckBox = uicheckbox(app.FileselectionPanel);
            app.registrationCheckBox.Text = ' registration';
            app.registrationCheckBox.Value = true;
            app.AutofocusButton = uibutton(app.FileselectionPanel, 'push');
            app.AutofocusButton.ButtonPushedFcn = createCallbackFcn(app, @AutofocusButtonPushed, true);
            app.AutofocusButton.Text = 'Autofocus';
            app.temporalfilterCheckBox = uicheckbox(app.FileselectionPanel);
            app.temporalfilterCheckBox.Text = 'temporal filter';
            app.temporalfilterCheckBox.Value = true;
            app.ParallelismDropDownLabel = uilabel(app.FileselectionPanel);
            app.ParallelismDropDownLabel.Text = 'Parallelism';
            app.ParallelismDropDown = uidropdown(app.FileselectionPanel);
            app.ParallelismDropDown.Items = {'multithread', 'gpu', 'singlethread'};
            app.ParallelismDropDown.Value = 'multithread';
            app.positioninfileSliderLabel = uilabel(app.FileselectionPanel);
            app.positioninfileSliderLabel.Text = {'position in file'; ''};
            app.positioninfileSlider = uislider(app.FileselectionPanel);
            app.positioninfileSlider.Limits = [0 1];
            app.positioninfileSlider.MajorTicks = [];
            app.positioninfileSlider.ValueChangedFcn = createCallbackFcn(app, @positioninfileSliderValueChanged, true);
            app.positioninfileSlider.MinorTicks = [];
            app.positioninfileSlider.Tooltip = {'Change value to display a different video timestamp in the gui.'};
            app.temporalfilterEditField = uieditfield(app.FileselectionPanel, 'numeric');
            app.temporalfilterEditField.Limits = [0 Inf];
            app.temporalfilterEditField.Tooltip = {'Laser wavelength in nanometers'};
            app.temporalfilterEditField.Value = 5;
            app.FiletypeDropDownLabel = uilabel(app.FileselectionPanel);
            app.FiletypeDropDownLabel.Text = 'File type';
            app.FiletypeDropDown = uidropdown(app.FileselectionPanel);
            app.FiletypeDropDown.Items = {'cine', 'raw'};
            app.FiletypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FiletypeDropDownValueChanged, true);
            app.FiletypeDropDown.Tooltip = {'Using .cine files is highly recommended'};
            app.FiletypeDropDown.Value = 'cine';
            app.PANICBUTTONButton = uibutton(app.FileselectionPanel, 'push');
            app.PANICBUTTONButton.Text = {'PANIC BUTTON'; 'click me if you are lost'; 'and you just want'; 'the damn video'};
            app.ImageviewPanel = uipanel(app.MainviewTab);
            app.ImageviewPanel.Title = 'Image view';
            app.GridLayout = uigridlayout(app.ImageviewPanel);
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.CLim = [0 1];
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.UIAxes_1 = uiaxes(app.GridLayout3);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.CLim = [0 1];
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.Slider2 = uislider(app.GridLayout3);
            app.Slider2.Limits = [-1 1];
            app.Slider2.MajorTicks = [];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider2.MinorTicks = [];
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.Slider = uislider(app.GridLayout3);
            app.Slider.Limits = [-1 1];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.PatientinformationsPanel = uipanel(app.GridLayout);
            app.PatientinformationsPanel.Title = 'Patient informations';
            app.FirstnameEditFieldLabel = uilabel(app.PatientinformationsPanel);
            app.FirstnameEditFieldLabel.Text = 'First name';
            app.FirstnameEditField = uieditfield(app.PatientinformationsPanel, 'text');
            app.LastnameEditFieldLabel = uilabel(app.PatientinformationsPanel);
            app.LastnameEditFieldLabel.Text = 'Last name';
            app.LastnameEditField = uieditfield(app.PatientinformationsPanel, 'text');
            app.NotesTextAreaLabel = uilabel(app.PatientinformationsPanel);
            app.NotesTextAreaLabel.Text = 'Notes';
            app.NotesTextArea = uitextarea(app.PatientinformationsPanel);
            app.YearofbirthEditFieldLabel = uilabel(app.PatientinformationsPanel);
            app.YearofbirthEditFieldLabel.Text = 'Year of birth';
            app.YearofbirthEditField = uieditfield(app.PatientinformationsPanel, 'numeric');
            app.YearofbirthEditField.Limits = [1900 Inf];
            app.YearofbirthEditField.Value = 1980;
            app.FeaturesPanel = uipanel(app.GridLayout);
            app.FeaturesPanel.Title = 'Features';
            app.valEditFieldLabel = uilabel(app.FeaturesPanel);
            app.valEditFieldLabel.Text = 'val';
            app.valEditField = uieditfield(app.FeaturesPanel, 'numeric');
            app.valEditField.Limits = [1 Inf];
            app.valEditField.Value = 5;
            app.registrationCheckBox_2 = uicheckbox(app.FeaturesPanel);
            app.registrationCheckBox_2.Tooltip = {'Activates video registration. Check this if the video is too shaky.'};
            app.registrationCheckBox_2.Text = 'registration';
            app.registrationCheckBox_2.Value = true;
            app.aberrationcompensationCheckBox = uicheckbox(app.FeaturesPanel);
            app.aberrationcompensationCheckBox.ValueChangedFcn = createCallbackFcn(app, @aberrationcompensationCheckBoxValueChanged, true);
            app.aberrationcompensationCheckBox.Tooltip = {'Activate aberration compensation via image entropy optimization. This might significantly increase the computation time.'};
            app.aberrationcompensationCheckBox.Text = 'aberration compensation';
            app.temporalfilterCheckBox_2 = uicheckbox(app.FeaturesPanel);
            app.temporalfilterCheckBox_2.Text = 'temporal filter';
            app.temporalfilterCheckBox_2.Value = true;
            app.CheckBox = uicheckbox(app.FeaturesPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Enable = 'off';
            app.CheckBox.Tooltip = {'Select a new batch stride for computing aberrations. Possible batch strides are multiples of the main stride. Checking this sets the main batch stride to the main batch size.'};
            app.CheckBox.Text = '';
            app.additionalzernikeranksLabel = uilabel(app.FeaturesPanel);
            app.additionalzernikeranksLabel.Text = {'additional '; 'zernike ranks'};
            app.additionalzernikeranksEditField = uieditfield(app.FeaturesPanel, 'numeric');
            app.additionalzernikeranksEditField.Limits = [1 Inf];
            app.additionalzernikeranksEditField.Enable = 'off';
            app.additionalzernikeranksEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.additionalzernikeranksEditField.Value = 2;
            app.optimizationplanDropDownLabel = uilabel(app.FeaturesPanel);
            app.optimizationplanDropDownLabel.Text = {'optimization'; 'plan'; ''};
            app.optimizationplanDropDown = uidropdown(app.FeaturesPanel);
            app.optimizationplanDropDown.Items = {'defocus', 'defocus and astig', 'defocus then astig'};
            app.optimizationplanDropDown.Enable = 'off';
            app.optimizationplanDropDown.Tooltip = {'Using .cine files is highly recommended'};
            app.optimizationplanDropDown.Value = 'defocus';
            app.batchsizefactorLabel = uilabel(app.FeaturesPanel);
            app.batchsizefactorLabel.Text = 'batch size factor';
            app.batchsizefactorEditField = uieditfield(app.FeaturesPanel, 'numeric');
            app.batchsizefactorEditField.Limits = [1 Inf];
            app.batchsizefactorEditField.Enable = 'off';
            app.batchsizefactorEditField.Tooltip = {'Number of zernike ranks that constitue the aberration projection polynomial space'};
            app.batchsizefactorEditField.Value = 2;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end